classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ApplyButton          matlab.ui.control.Button
        TypeDropDownLabel    matlab.ui.control.Label
        S1TypeDropDown       matlab.ui.control.DropDown
        S1Label              matlab.ui.control.Label
        OutputLabel          matlab.ui.control.Label
        S1EditFieldOutput    matlab.ui.control.NumericEditField
        S1UnitsOutput        matlab.ui.control.Label
        aLabel               matlab.ui.control.Label
        S1EditFieldA         matlab.ui.control.NumericEditField
        bLabel               matlab.ui.control.Label
        S1EditFieldB         matlab.ui.control.NumericEditField
        S1UnitsA             matlab.ui.control.Label
        S1UnitsB             matlab.ui.control.Label
        ApplyStatus          matlab.ui.control.Label
        S2UnitsB             matlab.ui.control.Label
        S2UnitsA             matlab.ui.control.Label
        bLabel_2             matlab.ui.control.Label
        S2EditFieldB         matlab.ui.control.NumericEditField
        aLabel_2             matlab.ui.control.Label
        S2EditFieldA         matlab.ui.control.NumericEditField
        S2UnitsOutput        matlab.ui.control.Label
        OutputLabel_2        matlab.ui.control.Label
        S2EditFieldOutput    matlab.ui.control.NumericEditField
        TypeDropDownLabel_2  matlab.ui.control.Label
        S2TypeDropDown       matlab.ui.control.DropDown
        S2Label              matlab.ui.control.Label
    end


    methods (Access = private)
    
        function SensorDropDownValueChanged(app, sensor, dropdown, EditField, unitsOutput, FieldA, FieldB)
            value = dropdown.Value;
            if strcmp(value,'Analogue') % compare dropdown value
                EditField.Enable = 'on';
                unitsOutput.Text = 'Volt';
                unitsOutput.Visible = 'on';
                FieldA.Enable = 'on';
                FieldB.Enable = 'on';
            elseif strcmp(value,'Digital') % compare dropdown value
                EditField.Enable = 'on';
                unitsOutput.Text = 'P/min';
                FieldA.Enable = 'off';
                FieldB.Enable = 'off';
            else % error case
                warning('Sensor '+ sensor + ' dropdown error!');
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ApplyStatus.Visible = 'off';
        end

        % Value changed function: S1TypeDropDown
        function S1TypeDropDownValueChanged(app, event)
            SensorDropDownValueChanged(app, ...
                '1', ...
                app.S1TypeDropDown, ...
                app.S1EditFieldOutput, ...
                app.S1UnitsOutput, ...
                app.S1EditFieldA, ...
                app.S1EditFieldB);
%             if strcmp(value,'Analogue') % compare dropdown value
%                 app.S1EditFieldOutput.Enable = 'on';
%                 app.S1UnitsOutput.Text = 'Volt';
%                 app.S1UnitsOutput.Visible = 'on';
%                 app.S1EditFieldA.Enable = 'on';
%                 app.S1EditFieldB.Enable = 'on';
%             elseif strcmp(value,'Digital') % compare dropdown value
%                 app.S1EditFieldOutput.Enable = 'on';
%                 app.S1UnitsOutput.Text = 'P/min';
%                 app.S1EditFieldA.Enable = 'off';
%                 app.S1EditFieldB.Enable = 'off';
%             else % error case
%                 warning('Sensor 1 dropdown error');
%             end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            type = app.S1TypeDropDown.Value;
            output = app.S1EditFieldOutput.Value;
            aVal = app.S1EditFieldA.Value;
            bVal = app.S1EditFieldB.Value;
            
            if strcmp(type, 'Analogue') % compare dropdown value
                 if setSensor(type, 1, output, aVal, bVal)
                     app.ApplyStatus.Visible = 'on';
                     app.ApplyStatus.Text = 'Success! ';
                 else
                     app.ApplyStatus.Visible = 'on';
                     app.ApplyStatus.Text = 'Failed! ';
                 end
            elseif strcmp(type, 'Digital') % compare dropdown value
                if setSensor(type, 1, output)
                    app.ApplyStatus.Visible = 'on';
                    app.ApplyStatus.Text = 'Success!';
                else
                    app.ApplyStatus.Visible = 'on';
                    app.ApplyStatus.Text = 'Failed!';
                end
            end
            
            type = app.S2TypeDropDown.Value;
            output = app.S2EditFieldOutput.Value;
            aVal = app.S2EditFieldA.Value;
            bVal = app.S2EditFieldB.Value;
            
            if strcmp(type, 'Analogue') % compare dropdown value
                 if setSensor(type, 2, output, aVal, bVal)
                     app.ApplyStatus.Visible = 'on';
                     app.ApplyStatus.Text = 'Success! ';
                 else
                     app.ApplyStatus.Visible = 'on';
                     app.ApplyStatus.Text = 'Failed! ';
                 end
            elseif strcmp(type, 'Digital') % compare dropdown value
                if setSensor(type, 2, output)
                    app.ApplyStatus.Visible = 'on';
                    app.ApplyStatus.Text = 'Success!';
                else
                    app.ApplyStatus.Visible = 'on';
                    app.ApplyStatus.Text = 'Failed!';
                end
            end
        end

        % Value changed function: S2TypeDropDown
        function S2TypeDropDownValueChanged(app, event)
            SensorDropDownValueChanged(app, ...
                '2', ...
                app.S2TypeDropDown, ...
                app.S2EditFieldOutput, ...
                app.S2UnitsOutput, ...
                app.S2EditFieldA, ...
                app.S2EditFieldB);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type:';
            app.S1TypeDropDown = uidropdown(app.UIFigure);
            app.S1TypeDropDown.Items = {'Analogue', 'Digital'};
            app.S1TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @S1TypeDropDownValueChanged, true);
            app.S1TypeDropDown.Value = 'Analogue';
            app.S1Label = uilabel(app.UIFigure);
            app.S1Label.Text = 'Sensor 1';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.S1EditFieldOutput = uieditfield(app.UIFigure, 'numeric');
            app.S1EditFieldOutput.Limits = [0 Inf];
            app.S1UnitsOutput = uilabel(app.UIFigure);
            app.S1UnitsOutput.Text = 'Volt';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a:';
            app.S1EditFieldA = uieditfield(app.UIFigure, 'numeric');
            app.S1EditFieldA.Limits = [0 Inf];
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b:';
            app.S1EditFieldB = uieditfield(app.UIFigure, 'numeric');
            app.S1EditFieldB.Limits = [0 Inf];
            app.S1UnitsA = uilabel(app.UIFigure);
            app.S1UnitsA.Text = 'V/unit';
            app.S1UnitsB = uilabel(app.UIFigure);
            app.S1UnitsB.Text = 'Volt';
            app.ApplyStatus = uilabel(app.UIFigure);
            app.ApplyStatus.Text = 'Success!';
            app.S2UnitsB = uilabel(app.UIFigure);
            app.S2UnitsB.Text = 'Volt';
            app.S2UnitsA = uilabel(app.UIFigure);
            app.S2UnitsA.Text = 'V/unit';
            app.bLabel_2 = uilabel(app.UIFigure);
            app.bLabel_2.Text = 'b:';
            app.S2EditFieldB = uieditfield(app.UIFigure, 'numeric');
            app.S2EditFieldB.Limits = [0 Inf];
            app.aLabel_2 = uilabel(app.UIFigure);
            app.aLabel_2.Text = 'a:';
            app.S2EditFieldA = uieditfield(app.UIFigure, 'numeric');
            app.S2EditFieldA.Limits = [0 Inf];
            app.S2UnitsOutput = uilabel(app.UIFigure);
            app.S2UnitsOutput.Text = 'Volt';
            app.OutputLabel_2 = uilabel(app.UIFigure);
            app.OutputLabel_2.Text = 'Output:';
            app.S2EditFieldOutput = uieditfield(app.UIFigure, 'numeric');
            app.S2EditFieldOutput.Limits = [0 Inf];
            app.TypeDropDownLabel_2 = uilabel(app.UIFigure);
            app.TypeDropDownLabel_2.Text = 'Type:';
            app.S2TypeDropDown = uidropdown(app.UIFigure);
            app.S2TypeDropDown.Items = {'Analogue', 'Digital'};
            app.S2TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @S2TypeDropDownValueChanged, true);
            app.S2TypeDropDown.Value = 'Analogue';
            app.S2Label = uilabel(app.UIFigure);
            app.S2Label.Text = 'Sensor 2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end